classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        importdataxlsButton  matlab.ui.control.Button
        USDBandarskrurdalurEditFieldLabel_2  matlab.ui.control.Label
        USD                  matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Data % Gögn úr excel skjali
    end

    methods (Access = public)
        % Reiknar út meanReturn, StdDev, Covariance Matrix og einingafyrir object Data
        function [meanReturn,StdDev,covMatrix,e,w,ERP,minSigma] = assets(obj)
            meanReturn = (mean(obj.Data)')/100;
            StdDev = (std(obj.Data))/100;
            covMatrix = (cov(obj.Data))/100;
            e = func5;
            function e = func5
                e = ones(length(StdDev),1);
                w = func6;
                function w = func6
                    w = (inv(covMatrix)*e)/(e'*inv(covMatrix)*e);
                    [ERP,minSigma] = func7;
                    function [ERP,minSigma] = func7
                        ERP = meanReturn'*w;
                        minSigma = sqrt(w'*covMatrix*w);
                    end
                end
            end 
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                url = ['http://apis.is/currency/m5'];
                S = webread(url);
                R = S.results.value;
                
                app.USD.Value = R;
        end

        % Button pushed function: importdataxlsButton
        function importdataxlsButtonPushed(app, event)
            fileName = uigetfile('*.xlsx');
            [num,txt,raw] = xlsread(fileName);
            app.Data = num;
            
            [meanReturn,StdDev,covMatrix,e,w,ERP,minSigma] = assets(app);
            %disp(meanReturn);
            %disp(StdDev);
            %disp(covMatrix);
            %disp(e);
            %disp(w);
            %disp(ERP);
            %disp(minSigma);
            
            plot(app.UIAxes,StdDev*100,meanReturn*100, '*');
            
            %label = {};
            %for i = 1:length(txt)
            %    label(i) = txt(i);
            %end
            
            %for i = 1:length(StdDev)
            %    text(StdDev(i)*100,meanReturn(i)*100,label(i))
            %end
            

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Portfolio management')
            xlabel(app.UIAxes, 'Std.Dev.')
            ylabel(app.UIAxes, 'Returns')
            app.importdataxlsButton = uibutton(app.UIFigure, 'push');
            app.importdataxlsButton.ButtonPushedFcn = createCallbackFcn(app, @importdataxlsButtonPushed, true);
            app.importdataxlsButton.Text = 'import data .xls';
            app.USDBandarskrurdalurEditFieldLabel_2 = uilabel(app.UIFigure);
            app.USDBandarskrurdalurEditFieldLabel_2.Text = 'USD Bandarískrur dalur';
            app.USD = uieditfield(app.UIFigure, 'numeric');
            app.USD.ValueDisplayFormat = '%.2f';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end